classdef PoweTestReport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PASSCALPowerTestReport          matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        RequiredDUTMetadataLabel        matlab.ui.control.Label
        RequredTestMetadataLabel        matlab.ui.control.Label
        DUTnameEditFieldLabel           matlab.ui.control.Label
        DUTnameEditField                matlab.ui.control.EditField
        DUTserialEditFieldLabel         matlab.ui.control.Label
        DUTserialEditField              matlab.ui.control.EditField
        DUTbarcodeEditFieldLabel        matlab.ui.control.Label
        DUTbarcodeEditField             matlab.ui.control.EditField
        ConfigurationofsensorinputsEditFieldLabel  matlab.ui.control.Label
        ConfigurationofsensorinputsEditField  matlab.ui.control.EditField
        SampleratesofDUTEditFieldLabel  matlab.ui.control.Label
        SampleratesofDUTEditField       matlab.ui.control.EditField
        GainsettingsofDUTEditFieldLabel  matlab.ui.control.Label
        GainsettingsofDUTEditField      matlab.ui.control.EditField
        GPSconfigurationofDUTEditFieldLabel  matlab.ui.control.Label
        GPSconfigurationofDUTEditField  matlab.ui.control.EditField
        GPSanttennatypeEditFieldLabel   matlab.ui.control.Label
        GPSanttennatypeEditField        matlab.ui.control.EditField
        FlashmemorytypeEditFieldLabel   matlab.ui.control.Label
        FlashmemorytypeEditField        matlab.ui.control.EditField
        FlashmemorybarcodeEditFieldLabel  matlab.ui.control.Label
        FlashmemorybarcodeEditField     matlab.ui.control.EditField
        DataarchivesettingsEditFieldLabel  matlab.ui.control.Label
        DataarchivesettingsEditField    matlab.ui.control.EditField
        PowermeterchassisEditFieldLabel  matlab.ui.control.Label
        PowermeterchassisEditField      matlab.ui.control.EditField
        OutputvoltageEditFieldLabel     matlab.ui.control.Label
        OutputvoltageEditField          matlab.ui.control.EditField
        CurrentlimitEditFieldLabel      matlab.ui.control.Label
        CurrentlimitEditField           matlab.ui.control.EditField
        SamplerateEditFieldLabel        matlab.ui.control.Label
        SamplerateEditField             matlab.ui.control.EditField
        DurationinhoursEditFieldLabel   matlab.ui.control.Label
        DurationinhoursEditField        matlab.ui.control.EditField
        ParametersrecordedEditFieldLabel  matlab.ui.control.Label
        ParametersrecordedEditField     matlab.ui.control.EditField
        DatafilenameEditFieldLabel      matlab.ui.control.Label
        DatafilenameEditField           matlab.ui.control.EditField
        TestsstarttimeEditField         matlab.ui.control.EditField
        DatadownloadtimeEditFieldLabel  matlab.ui.control.Label
        DatadownloadtimeEditField       matlab.ui.control.EditField
        ImportTestDataButton            matlab.ui.control.Button
        SaveTestReportButton            matlab.ui.control.Button
        DUTmetadatanotesTextAreaLabel   matlab.ui.control.Label
        DUTmetadatanotesTextArea        matlab.ui.control.TextArea
        TestmetadatanotesTextAreaLabel  matlab.ui.control.Label
        TestmetadatanotesTextArea       matlab.ui.control.TextArea
        SaveWindow1Button               matlab.ui.control.Button
        SaveWindow2Button               matlab.ui.control.Button
        SaveWindow3Button               matlab.ui.control.Button
        SaveWindow4Button               matlab.ui.control.Button
        PlotPowerTestDataButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
          data;
            
            formatSpec;
            N;
            text1;
            text2;
            text3;
            text4;
            text5;
            text6;
            text7;
            
            %Extract meta data
            meta_data1;
            meta_data2;
            meta_data3;
            meta_data4 ;
            
            %Separate numbers from meta data
            extract_meta_data2;
            
            Sample_Rate;  
            Sam;

            samp_data;
            col_data ;
            size_col;
            Duration; % in hours
            t; %Colum 1, time
            time;
            A; %Colum 2, current
            V; %Colum 3, voltage
            P ; %Colum 2 multiplied by colum 3 to get power
            %Calculate min, ave, and max
            minI;
            maxI;
            minV;
            maxV;
            minP;
            maxP;
            aveP;
            %windows
            img1;
            img2;
            img3;
            img4;
            %Cell  variables
            DUTnam; 
            DUTsernum;
            DUTBar; 
            Configurationsein; 	
            SamplerDUT; 
            GainsDUT; 
            GPSconfigDUT; 			
            GPSantt; 
            Dataarchsett; 			
            Flashmemtype; 
            Flashmembarcode; 			
            DUTmetnotes; 
            Outputvolt; 
            Currentlim;
            Paramesrec; 
            Datafilnam; 
            Datadowntim;
            Testmetnotes; 
            xlimits1;
            ylimits1;
            xlimits2;
            ylimits2;
            xlimits3;
            ylimits3;
            xlimits4;
            ylimits4;
            ax;
            fig1;
            fig2;
            fig3;
            fig4;
            sam;
            duration;


            Desx = 'Xaxis size: ';
            Desy = 'Yaxis size: ';
            f1;
            f2;
            f3;
            f4;
            Sheet2;
            Sheet3;
            Sheet4;
            Sheet5;

            
            MaxI1;
            MinP1;
            MaxP1;
            AveP1;
            MaxI2;
            MinP2;
            MaxP2;
            AveP2;
            MaxI3;
            MinP3;
            MaxP3;
            AveP3;
            MaxI4;
            MinP4;
            MaxP4;
            AveP4;
            testime;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportTestDataButton
        function ImportTestDataButtonPushed(app, event)
            cla(app.UIAxes) 
            app.PowermeterchassisEditField.Value = '';
            app.SamplerateEditField.Value = '';
            app.DurationinhoursEditField.Value = '';
            app.TestsstarttimeEditField.Value = '';
            app.DUTnameEditField.Value = '';
            app.DUTserialEditField.Value = '';
            app.DUTbarcodeEditField.Value = '';
            app.ConfigurationofsensorinputsEditField.Value = '';
            app.SampleratesofDUTEditField.Value = '';
            app.GainsettingsofDUTEditField.Value = '';
            app.GPSconfigurationofDUTEditField.Value = '';
            app.GPSanttennatypeEditField.Value = '';
            app.DataarchivesettingsEditField.Value = '';
            app.FlashmemorytypeEditField.Value = '';
            app.FlashmemorybarcodeEditField.Value = '';
            app.DUTmetadatanotesTextArea.Value = '';
            app.OutputvoltageEditField.Value = '';
            app.CurrentlimitEditField.Value = '';
            app.ParametersrecordedEditField.Value = '';
            app.DatafilenameEditField.Value = '';
            app.DatadownloadtimeEditField.Value = '';
            app.TestmetadatanotesTextArea.Value = '';
            delete(app.img1)
            delete(app.img2)
            delete(app.img3)
            delete(app.img4)
            app.xlimits1 = '';
            app.xlimits2 = '';
            app.xlimits3 = '';
            app.xlimits4 = '';
            app.ylimits1 = '';
            app.ylimits2 = '';
            app.ylimits3 = '';
            app.ylimits4 = '';

            %Read data from test
            [file,path] = uigetfile({'*.csv'; '*.xlsx';'*.txt'; '*.cir'});
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            
            
            app.data = fopen(file, 'r');
            
            app.formatSpec = '%s';
            app.N = 4;
            app.text1 = textscan(app.data, app.formatSpec, app.N, 'Delimiter', ',');
            app.text2 = textscan(app.data, app.formatSpec, app.N, 'Delimiter', ',');
            app.text3 = textscan(app.data, app.formatSpec, app.N, 'Delimiter', ',');
            app.text4 = textscan(app.data, app.formatSpec, app.N, 'Delimiter', ',');
            app.text5 = textscan(app.data, app.formatSpec, app.N, 'Delimiter', ',');
            app.text6 = textscan(app.data, app.formatSpec, app.N, 'Delimiter', ',');
            app.text7 = textscan(app.data, app.formatSpec, app.N, 'Delimiter', ',');
            
            %Extract meta data
            app.meta_data1 = app.text1{1}(1:1,:);
            app.meta_data2 = app.text1{1}(2:2,:);
            app.meta_data3 = app.text1{1}(3:3,:);
            app.meta_data4 = app.text1{1}{4:4,:};
            
            %Separate numbers from meta data
            app.extract_meta_data2 = string(app.meta_data2);
            
            app.Sample_Rate = sscanf(app.extract_meta_data2, '"Sample interval:%f64"');  
            app.Sam = 1./app.Sample_Rate;
            app.sam = round(app.Sam, 1,"significant");
            app.samp_data =  textscan(app.data, '%f%f%f', 'delimiter', ',');
            app.col_data = [app.samp_data{1} app.samp_data{2} app.samp_data{3}];
            app.size_col = length(app.col_data(:,1) );
            
            app.Duration = ((app.size_col*app.Sample_Rate)/3600); % in hours
            app.duration = round(app.Duration, 3, "decimals");
            
            app.PowermeterchassisEditField.Value = num2str(string(app.meta_data1));
            app.SamplerateEditField.Value = num2str(num2str(app.Sample_Rate));
            app.DurationinhoursEditField.Value = num2str(num2str(app.duration));
            app.TestsstarttimeEditField.Value = num2str(string(app.meta_data4));
            

            end
            
            
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
%          
        
        end

        % Button pushed function: SaveTestReportButton
        function SaveTestReportButtonPushed(app, event)
%               
            app.meta_data1 = string(num2str(app.PowermeterchassisEditField.Value));
            app.Sample_Rate = num2str(num2str(app.SamplerateEditField.Value));
            app.Duration = num2str(num2str(app.DurationinhoursEditField.Value));
            app.meta_data4 = string(num2str(app.TestsstarttimeEditField.Value));
            Date = datestr(now);
            app.DUTnam = string(num2str(app.DUTnameEditField.Value));
            app.DUTsernum = string(num2str(app.DUTserialEditField.Value));
            app.DUTBar = string(num2str(app.DUTbarcodeEditField.Value));
            app.Configurationsein = string(num2str(app.ConfigurationofsensorinputsEditField.Value));
            app.SamplerDUT = string(num2str(app.SampleratesofDUTEditField.Value));
            app.GainsDUT = string(num2str(app.GainsettingsofDUTEditField.Value));
            app.GPSconfigDUT =string(num2str(app.GPSconfigurationofDUTEditField.Value));
            app.GPSantt = string(num2str(app.GPSanttennatypeEditField.Value));
            app.Dataarchsett = string(num2str(app.DataarchivesettingsEditField.Value));
            app.Flashmemtype = string(num2str(app.FlashmemorytypeEditField.Value));
            app.Flashmembarcode = string(num2str(app.FlashmemorybarcodeEditField.Value));
            app.DUTmetnotes = string(string(app.DUTmetadatanotesTextArea.Value));
            app.Outputvolt = string(num2str(app.OutputvoltageEditField.Value));
            app.Currentlim = string(num2str(app.CurrentlimitEditField.Value));
            app.Paramesrec = string(num2str(app.ParametersrecordedEditField.Value));
            app.Datafilnam = string(num2str(app.DatafilenameEditField.Value));
            app.Datadowntim = string(num2str(app.DatadownloadtimeEditField.Value));
            app.Testmetnotes = string(string(app.TestmetadatanotesTextArea.Value));
            
              
            %Create a Results templete
            fileName = 'C:\Users\Hilda Martin\Documents\MATLAB\PASSCALPowerTestMATLABApp\DataResults.xlsx';
            fileName_template =('C:\Users\Hilda Martin\Documents\MATLAB\PASSCALPowerTestMATLABApp\Power_Cons_Temp.xlsx');
            copyfile(fileName_template,fileName);


            %Create a server
            Excel = actxserver('Excel.Application');
            Workbooks = Excel.Workbooks;
            % Make Excel visible
            Excel.Visible = 1;
            % Open Excel file
            Workbook = Workbooks.Open(fileName);
            
            % populate excel sheet cells with data
            
            sheetnum=1;
            
            range = 'D8:D8';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value',app.meta_data1);
            
            range = 'D11:D11';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.sam);
            
            range = 'D12:D12';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.duration);
            
            range = 'D15:D15';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.meta_data4);
            
            range = 'C3:C3';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', Date);
            
            
            range = 'B8:B8';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.DUTnam );
            
            range = 'B9:B9';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.DUTsernum);
            
            
            range = 'B10:B10';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.DUTBar);
            
            
            range = 'B11:B11';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Configurationsein);
            
            
            range = 'B12:B12';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.SamplerDUT);
            
            range = 'B13:B13';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.GainsDUT);
            
            
            range = 'B14:B14';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.GPSconfigDUT);


            range = 'B15:B15';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.GPSantt);


            range = 'B16:B16';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Dataarchsett);
            
             
            range = 'B17:B17';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Flashmemtype);
            
            range = 'B18:B18';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Flashmembarcode);
             
            range = 'A20:B23';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.DUTmetnotes);
            
            
            range = 'D9:D9';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Outputvolt);
            
            range = 'D10:D10';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Currentlim);
            
            range = 'D13:D13';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Paramesrec);
            
            range = 'D14:D14';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Datafilnam);
            
            range = 'D16:D16';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Datadowntim);
            
            range = 'C20:D23';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Testmetnotes);
            
            range = 'B30:B30';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.AveP1);
            
            range = 'B31:B31';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MaxP1);
            
            range = 'B32:B32';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MinP1);
            
            range = 'B33:B33';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MaxI1);
            
            range = 'D30:D30';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.AveP2);
            
            range = 'D31:D31';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MaxP2);
            
            range = 'D32:D32';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MinP2);
            
            range = 'D33:D33';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MaxI2);
            
            range = 'B40:B40';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.AveP3);
            
            range = 'B41:B41';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MaxP3);
            
            range = 'B42:B42';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MinP3);
            
            range = 'B43:B43';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MaxI3);
            
            range = 'D40:D40';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.AveP4);
            
            range = 'D41:D41';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MaxP4);
            
            range = 'D42:D42';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MinP4);
            
            range = 'D43:D43';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            % Put MATLAB data into Excel
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.MaxI4);
            
            
            range = 'B27:B27';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.xlimits1);
            
            range = 'B28:B28';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.ylimits1);
            
            range = 'D27:D27';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.xlimits2);
            
            range = 'D28:D28';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.ylimits2);
            
            range = 'B37:B37';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.xlimits3);
            
            range = 'B38:B38';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.ylimits3);
            
            range = 'D37:D37';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;        
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.xlimits4);
            
            range = 'D38:D38';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.ylimits4);
            
            range = 'A27:A27';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Desx);
            
            range = 'A28:A28';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Desy);
            
            range = 'C27:C27';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Desx);
            
            range = 'C28:C28';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Desy);
            
            range = 'A37:A37';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Desx);
            
            range = 'A38:A38';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Desy);
            
            range = 'C37:C37';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;        
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Desx);
            
            range = 'c38:c38';
            Sheets = Excel.ActiveWorkBook.Sheets;
            sheet1 = get(Sheets, 'Item', sheetnum);
            invoke(sheet1, 'Activate');
            Activesheet = Excel.Activesheet;
            ActivesheetRange = get(Activesheet,'Range',range);
            set(ActivesheetRange, 'Value', app.Desy);
            
            
            % Add image
               if isempty(app.img1)
                 delete(app.Sheet2);
                 delete(app.img1);
               else
                 sheetnum = 2;
                 Sheets = Excel.ActiveWorkBook.Sheets;
                 app.Sheet2 = get(Sheets, 'Item', sheetnum);
                 app.Sheet2.Activate;
                 Shapes = app.Sheet2.Shapes;
                 Shapes.AddPicture([pwd '\', app.img1], 0, 1, 55, 120, 375, 275);
               end


                % Add image
              if isempty(app.img2)
                delete(app.Sheet3); 
                delete(app.img2);
              else   
                sheetnum = 3;
                Sheets = Excel.ActiveWorkBook.Sheets;
                app.Sheet3 = get(Sheets, 'Item', sheetnum);
                app.Sheet3.Activate;
                Shapes = app.Sheet3.Shapes;
                Shapes.AddPicture([pwd '\', app.img2], 0, 1, 55, 120, 375, 275);
              end 
              
              % Add image
              if isempty(app.img3)
                delete(app.Sheet4);
                delete(app.img3);
              else   
                sheetnum = 4;
                Sheets = Excel.ActiveWorkBook.Sheets;
                app.Sheet4 = get(Sheets, 'Item', sheetnum);
                app.Sheet4.Activate;
                Shapes = app.Sheet4.Shapes;
                Shapes.AddPicture([pwd '\', app.img3], 0, 1, 55, 120, 375, 275);
              end   
              
              
                % Add image
              if isempty(app.img4)
                delete(app.Sheet5);
                delete(app.img4);
              else
                sheetnum = 5;
                Sheets = Excel.ActiveWorkBook.Sheets;
                app.Sheet5 = get(Sheets, 'Item', sheetnum);
                app.Sheet5.Activate;
                Shapes = app.Sheet5.Shapes;
                Shapes.AddPicture([pwd '\', app.img4], 0, 1, 55, 120, 375, 275);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% ^  ^  ^   ^    ^    ^
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 0 1   x   y    size
              end
       
                 %invoke workbook to save to pdf
                invoke(Workbook,'Save');
                Workbook = Excel.Workbooks.Open(sprintf('%s',fileName));
                % print this book to PDF
                clear('Workbook');
                [outfile, path] = uiputfile({'*.pdf'; '*.xlsx'}, 'Save as', 'C:\Users\Hilda Martin\Documents\MATLAB\PDF_folders');
                
                   
                if isequal(outfile,0) || isequal(path,0)
                   disp('User clicked Cancel.')
                else
                   disp(['User selected ',fullfile(path,outfile),...
                         ' and then clicked Save.'])
                   Workbook.ExportAsFixedFormat('xlTypePDF', fullfile(path,outfile));
                   
                end
                 
        
        end

        % Button pushed function: SaveWindow1Button
        function SaveWindow1ButtonPushed(app, event)
            % Create a temporary figure with axes.
           
            app.fig1 = figure;
            app.fig1.Visible = 'off';
            figAxes = axes(app.fig1);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren= app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes);
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.XLabel.String = app.UIAxes.XLabel.String;
            figAxes.YLabel.String = app.UIAxes.YLabel.String;
            figAxes.Title.String = app.UIAxes.Title.String;
            app.xlimits1 = num2str(figAxes.XLim(1,:));
            app.ylimits1 = num2str(figAxes.YLim(1,:));
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            a1 =  ceil((figAxes.XLim(:,1) + 0.0070246404)./app.Sample_Rate);
            b1 = ceil((figAxes.XLim(:,end) - 0.0070246404)./app.Sample_Rate);
            rangeP = app.P(a1:b1);          
            rangeI = app.A(a1:b1);
                   
            maxI1 = max(rangeI);
            minP1 = min(rangeP);
            maxP1 = max(rangeP);
            aveP1 = mean(rangeP);
            app.MaxI1 = round(maxI1, 2,"decimals");
            app.MinP1 = round(minP1, 2,"decimals");
            app.MaxP1 = round(maxP1, 2,"decimals");
            app.AveP1 = round(aveP1, 2,"decimals");
            delete('C:\Users\Hilda Martin\Documents\MATLAB\PASSCALPowerTestMATLABApp/jpg.fig');
            for K = 1 : length(app.fig1)
               app.img1 = 'figure1_%02d.jpg';
               saveas(app.fig1(K), app.img1);
            end
            % Delete the temporary figure. 
            
            app.f1 = msgbox('Window 1 Saved','Saved');
            
        end

        % Button pushed function: SaveWindow2Button
        function SaveWindow2ButtonPushed(app, event)
            % Create a temporary figure with axes.
            
            app.fig2 = figure;
            app.fig2.Visible = 'off';
            figAxes = axes(app.fig2);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes);
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.XLabel.String = app.UIAxes.XLabel.String;
            figAxes.YLabel.String = app.UIAxes.YLabel.String;
            figAxes.Title.String = app.UIAxes.Title.String;          
            app.xlimits2 = num2str( figAxes.XLim(1,:));
            app.ylimits2 = num2str( figAxes.YLim(1,:));
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            a2 =  floor((figAxes.XLim(:,1) + 0.0070246404)./app.Sample_Rate);
            b2 = ceil((figAxes.XLim(:,end) - 0.0070246404)./app.Sample_Rate);
            rangeP = app.P(a2:b2);          
            rangeI = app.A(a2:b2);
                   
            maxI2 = max(rangeI);
            minP2 = min(rangeP);
            maxP2 = max(rangeP);
            aveP2 = mean(rangeP);
            app.MaxI2 = round(maxI2, 2,"decimals");
            app.MinP2 = round(minP2, 2,"decimals");
            app.MaxP2 = round(maxP2, 2,"decimals");
            app.AveP2 = round(aveP2, 2,"decimals");
            delete('C:\Users\Hilda Martin\Documents\MATLAB\PASSCALPowerTestMATLABApp/jpg.fig');
            for K = 1 : length(app.fig2)
               app.img2 = 'figure2_%02d.jpg';
               saveas(app.fig2(K), app.img2);
            end
            % Delete the temporary figure. 
            
            app.f2 = msgbox('Window 2 Saved','Saved');
            
        end

        % Button pushed function: SaveWindow3Button
        function SaveWindow3ButtonPushed(app, event)
            % Create a temporary figure with axes.
            
            app.fig3 = figure;
            app.fig3.Visible = 'off';
            figAxes = axes(app.fig3);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes);
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.XLabel.String = app.UIAxes.XLabel.String;
            figAxes.YLabel.String = app.UIAxes.YLabel.String;
            figAxes.Title.String = app.UIAxes.Title.String;           
            app.xlimits3 = num2str( figAxes.XLim(1,:));
            app.ylimits3 = num2str( figAxes.YLim(1,:));
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            a3 =  floor((figAxes.XLim(:,1) + 0.0070246404)./app.Sample_Rate);
            b3 = ceil((figAxes.XLim(:,end) - 0.0070246404)./app.Sample_Rate);
            rangeP = app.P(a3:b3);          
            rangeI = app.A(a3:b3);
                   
            maxI3 = max(rangeI);
            minP3 = min(rangeP);
            maxP3 = max(rangeP);
            aveP3 = mean(rangeP);
            app.MaxI3 = round(maxI3, 2,"decimals");
            app.MinP3 = round(minP3, 2,"decimals");
            app.MaxP3 = round(maxP3, 2,"decimals");
            app.AveP3 = round(aveP3, 2,"decimals");
            delete('C:\Users\Hilda Martin\Documents\MATLAB\PASSCALPowerTestMATLABApp/jpg.fig');
            for K = 1 : length(app.fig3)
               app.img3 = 'figure3_%02d.jpg';
               saveas(app.fig3(K), app.img3);
            end
            % Delete the temporary figure. 
             app.f3 = msgbox('Window 3 Saved','Saved');
            
        end

        % Button pushed function: SaveWindow4Button
        function SaveWindow4ButtonPushed(app, event)
            % Create a temporary figure with axes.
            
            app.fig4 = figure;
            app.fig4.Visible = 'off';
            figAxes = axes(app.fig4);
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.UIAxes.XLim;
            figAxes.YLim = app.UIAxes.YLim;
            figAxes.XLabel.String = app.UIAxes.XLabel.String;
            figAxes.YLabel.String = app.UIAxes.YLabel.String;
            figAxes.Title.String = app.UIAxes.Title.String;
            app.xlimits4 = num2str( figAxes.XLim(1,:));
            app.ylimits4 = num2str( figAxes.YLim(1,:));
            figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
            a4 =  floor((figAxes.XLim(:,1) + 0.0070246404)./app.Sample_Rate);
            b4 = ceil((figAxes.XLim(:,end) - 0.0070246404)./app.Sample_Rate);
            rangeP = app.P(a4:b4);          
            rangeI = app.A(a4:b4);
                   
            maxI4 = max(rangeI);
            minP4 = min(rangeP);
            maxP4 = max(rangeP);
            aveP4 = mean(rangeP);
            app.MaxI4 = round(maxI4, 2,"decimals");
            app.MinP4 = round(minP4, 2,"decimals");
            app.MaxP4 = round(maxP4, 2,"decimals");
            app.AveP4 = round(aveP4, 2,"decimals");
            delete('C:\Users\Hilda Martin\Documents\MATLAB\PASSCALPowerTestMATLABApp/jpg.fig');
            for K = 1 : length(app.fig4)
               app.img4 = 'figure4_%02d.jpg';
               saveas(app.fig4(K), app.img4);
            end
            % Delete the temporary figure. 
            
             app.f4 = msgbox('Window 4 Saved','Saved');
             
        end

        % Button pushed function: PlotPowerTestDataButton
        function PlotPowerTestDataButtonPushed(app, event)
            %Plot
            app.t = app.col_data(:,1); %Colum 1, time
            app.time = app.Sample_Rate.*app.t;
            app.A = app.col_data(:,2); %Colum 2, current
            app.V = app.col_data(:,3); %Colum 3, voltage
            app.P = app.col_data(:,2).*app.col_data(:,3); %Colum 2 multiplied by colum 3 to get power
             
            %Calculate min and max
            app.maxI = min(app.A(:));
            
            app.minV = min(app.V(:));
            app.maxV = max(app.V(:));
            delete(app.img1)
            delete(app.img2)
            delete(app.img3)
            delete(app.img4)
            app.xlimits1 = '';
            app.xlimits2 = '';
            app.xlimits3 = '';
            app.xlimits4 = '';
            app.ylimits1 = '';
            app.ylimits2 = '';
            app.ylimits3 = '';
            app.ylimits4 = '';
            
            plot(app.UIAxes, app.time, app.P);
            title(app.UIAxes,'Power');
            xlabel(app.UIAxes,'Seconds');
            ylabel(app.UIAxes,'Watts');
            zoom(app.UIAxes,'on');
            grid(app.UIAxes,'on');
             
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PASSCALPowerTestReport = uifigure('Visible', 'off');
            app.PASSCALPowerTestReport.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.PASSCALPowerTestReport);
            title(app.UIAxes, 'Power ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RequiredDUTMetadataLabel = uilabel(app.PASSCALPowerTestReport);
            app.RequiredDUTMetadataLabel.Text = 'Required DUT Metadata';
            app.RequredTestMetadataLabel = uilabel(app.PASSCALPowerTestReport);
            app.RequredTestMetadataLabel.Text = 'Requred Test Metadata';
            app.DUTnameEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.DUTnameEditFieldLabel.Text = 'DUT name';
            app.DUTnameEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.DUTserialEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.DUTserialEditFieldLabel.Text = 'DUT serial #';
            app.DUTserialEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.DUTbarcodeEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.DUTbarcodeEditFieldLabel.Text = 'DUT barcode';
            app.DUTbarcodeEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.ConfigurationofsensorinputsEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.ConfigurationofsensorinputsEditFieldLabel.Text = 'Configuration of sensor inputs';
            app.ConfigurationofsensorinputsEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.SampleratesofDUTEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.SampleratesofDUTEditFieldLabel.Text = 'Sample rate(s) of DUT';
            app.SampleratesofDUTEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.GainsettingsofDUTEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.GainsettingsofDUTEditFieldLabel.Text = 'Gain settings of DUT';
            app.GainsettingsofDUTEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.GPSconfigurationofDUTEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.GPSconfigurationofDUTEditFieldLabel.Text = 'GPS configuration of DUT';
            app.GPSconfigurationofDUTEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.GPSanttennatypeEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.GPSanttennatypeEditFieldLabel.Text = 'GPS anttenna type';
            app.GPSanttennatypeEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.FlashmemorytypeEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.FlashmemorytypeEditFieldLabel.Text = 'Flash memory type';
            app.FlashmemorytypeEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.FlashmemorybarcodeEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.FlashmemorybarcodeEditFieldLabel.Text = 'Flash memory barcode';
            app.FlashmemorybarcodeEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.DataarchivesettingsEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.DataarchivesettingsEditFieldLabel.Text = 'Data archive settings';
            app.DataarchivesettingsEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.PowermeterchassisEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.PowermeterchassisEditFieldLabel.Text = 'Power meter chassis';
            app.PowermeterchassisEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.OutputvoltageEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.OutputvoltageEditFieldLabel.Text = 'Output voltage';
            app.OutputvoltageEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.CurrentlimitEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.CurrentlimitEditFieldLabel.Text = 'Current limit';
            app.CurrentlimitEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.SamplerateEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.SamplerateEditFieldLabel.Text = 'Sample rate';
            app.SamplerateEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.DurationinhoursEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.DurationinhoursEditFieldLabel.Text = 'Duration in hours';
            app.DurationinhoursEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.ParametersrecordedEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.ParametersrecordedEditFieldLabel.Text = 'Parameters recorded';
            app.ParametersrecordedEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.DatafilenameEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.DatafilenameEditFieldLabel.Text = 'Data file name';
            app.DatafilenameEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.TestsstarttimeEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.DatadownloadtimeEditFieldLabel = uilabel(app.PASSCALPowerTestReport);
            app.DatadownloadtimeEditFieldLabel.Text = 'Data download time';
            app.DatadownloadtimeEditField = uieditfield(app.PASSCALPowerTestReport, 'text');
            app.ImportTestDataButton = uibutton(app.PASSCALPowerTestReport, 'push');
            app.ImportTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTestDataButtonPushed, true);
            app.ImportTestDataButton.Text = 'Import Test Data';
            app.SaveTestReportButton = uibutton(app.PASSCALPowerTestReport, 'push');
            app.SaveTestReportButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTestReportButtonPushed, true);
            app.SaveTestReportButton.Text = 'Save Test Report';
            app.DUTmetadatanotesTextAreaLabel = uilabel(app.PASSCALPowerTestReport);
            app.DUTmetadatanotesTextAreaLabel.Text = 'DUT metadata notes:';
            app.DUTmetadatanotesTextArea = uitextarea(app.PASSCALPowerTestReport);
            app.TestmetadatanotesTextAreaLabel = uilabel(app.PASSCALPowerTestReport);
            app.TestmetadatanotesTextAreaLabel.Text = 'Test metadata notes:';
            app.TestmetadatanotesTextArea = uitextarea(app.PASSCALPowerTestReport);
            app.SaveWindow1Button = uibutton(app.PASSCALPowerTestReport, 'push');
            app.SaveWindow1Button.ButtonPushedFcn = createCallbackFcn(app, @SaveWindow1ButtonPushed, true);
            app.SaveWindow1Button.Text = 'Save Window 1';
            app.SaveWindow2Button = uibutton(app.PASSCALPowerTestReport, 'push');
            app.SaveWindow2Button.ButtonPushedFcn = createCallbackFcn(app, @SaveWindow2ButtonPushed, true);
            app.SaveWindow2Button.Text = 'Save Window 2';
            app.SaveWindow3Button = uibutton(app.PASSCALPowerTestReport, 'push');
            app.SaveWindow3Button.ButtonPushedFcn = createCallbackFcn(app, @SaveWindow3ButtonPushed, true);
            app.SaveWindow3Button.Text = 'Save Window 3';
            app.SaveWindow4Button = uibutton(app.PASSCALPowerTestReport, 'push');
            app.SaveWindow4Button.ButtonPushedFcn = createCallbackFcn(app, @SaveWindow4ButtonPushed, true);
            app.SaveWindow4Button.Text = 'Save Window 4';
            app.PlotPowerTestDataButton = uibutton(app.PASSCALPowerTestReport, 'push');
            app.PlotPowerTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPowerTestDataButtonPushed, true);
            app.PlotPowerTestDataButton.Text = 'Plot Power Test Data';
            % Show the figure after all components are created
            app.PASSCALPowerTestReport.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PoweTestReport

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PASSCALPowerTestReport)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PASSCALPowerTestReport)
        end
    end
end